classdef ES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        systemtuningworkbenchPanel      matlab.ui.container.Panel
        TrajectoryPanel                 matlab.ui.container.Panel
        AxeTrajectory                   matlab.ui.control.UIAxes
        PovCovEastSlider                matlab.ui.control.Slider
        PovCovEastSliderLabel           matlab.ui.control.Label
        PovCovNorthSlider               matlab.ui.control.Slider
        PovCovNorthSliderLabel          matlab.ui.control.Label
        PovCovUpSlider                  matlab.ui.control.Slider
        PovCovUpSliderLabel             matlab.ui.control.Label
        BiasinstabilitySlider           matlab.ui.control.Slider
        BiasinstabilitySliderLabel      matlab.ui.control.Label
        GyroPSDSlider                   matlab.ui.control.Slider
        GyroPSDSliderLabel              matlab.ui.control.Label
        AcclPSDSlider                   matlab.ui.control.Slider
        AcclPSDSliderLabel              matlab.ui.control.Label
        ErrorPlotsPanel                 matlab.ui.container.Panel
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        APPLYTUNINGRUNES_EKFButton      matlab.ui.control.Button
        EnableGyroscopeCheckBox         matlab.ui.control.CheckBox
        EnableAccelemetorCheckBox       matlab.ui.control.CheckBox
        EnableGNSSCheckBox              matlab.ui.control.CheckBox
        EnableDisablesensorsLabel       matlab.ui.control.Label
        EditFieldEast                   matlab.ui.control.NumericEditField
        EditFieldNorth                  matlab.ui.control.NumericEditField
        EditFieldUP                     matlab.ui.control.NumericEditField
        ResetButtonEast                 matlab.ui.control.Button
        ResetButtonNorth                matlab.ui.control.Button
        ResetButtonUp                   matlab.ui.control.Button
        GNSSMeasurmentNoiseRMatrixLabel  matlab.ui.control.Label
        ResetButtonBias                 matlab.ui.control.Button
        EditFieldBias                   matlab.ui.control.NumericEditField
        ResetButtongyro                 matlab.ui.control.Button
        EditFieldgyro                   matlab.ui.control.NumericEditField
        ResetButtonAccl                 matlab.ui.control.Button
        EditFieldAccl                   matlab.ui.control.NumericEditField
        IMUNoiseParametersQMatrixLabel  matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        DatasetEditField                matlab.ui.control.EditField
        DatasetSelectionLabel           matlab.ui.control.Label
        AxeAcc                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SelectedFilePath = ''; % إضافة هذا السط % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
[file, path] = uigetfile({'*.mat', 'MAT-files (*.mat)'}, 'اختر ملف الداتا');
        
        if file ~= 0
            % دمج المسار مع اسم الملف وحفظه في الـ Edit Field
            full_path = fullfile(path, file);
            app.DatasetEditField.Value = full_path;
        end
        end

        % Button pushed function: APPLYTUNINGRUNES_EKFButton
        function APPLYTUNINGRUNES_EKFButtonPushed(app, event)
     
        % قراءة البارامترات من الـ Sliders
        var_imu_f = app.AcclPSDSlider.Value;
        var_imu_w = app.GyroPSDSlider.Value;
        
        % قراءة حالات الـ Checkboxes الخاصة بتفعيل/تعطيل الحساسات
        enable_gnss = app.EnableGNSSCheckBox.Value;
        enable_acc  = app.EnableAccelemetorCheckBox.Value;
        enable_gyro = app.EnableGyroscopeCheckBox.Value;
        
        % قراءة نوع الـ Motion Model من الـ DropDown (تأكد من اسم المكون عندك لو مشابها لـ MotionModelDropDown)
      
        
        % قراءة اسم أو مسار الملف من الـ Edit Field
        dataset_name = app.DatasetEditField.Value;
        if isempty(dataset_name)
            app.EditField.Value = 'dataset.mat';
            uialert(app.UIFigure, 'الرجاء اختيار ملف الداتا أولاً باستخدام زر Browse!', 'تنبيه');
            return;
        end
        
        % مصفوفة ضوضاء الـ GNSS (R Matrix)
        R_gnss = diag([...
            app.PovCovEastSlider.Value, ...
            app.PovCovNorthSlider.Value, ...
            app.PovCovUpSlider.Value]);
        
        % التحقق من وجود الملف وتحميله
        if ~isfile(dataset_name)
            uialert(app.UIFigure, ['الملف غير موجود في المسار: ', dataset_name], 'خطأ في الملف');
            return;
        end
        
        loaded_data = load(dataset_name);
        if isfield(loaded_data, 'data1')
            dataset = loaded_data.data1;
        else
            fields = fieldnames(loaded_data);
            dataset = loaded_data.(fields{1});
        end
        
        imu_f = dataset.imu_f.data;
        imu_w = dataset.imu_w.data;
        t_imu = dataset.imu_f.t;
        gnss_data = dataset.gnss.data;
        gnss_t = dataset.gnss.t;
        num_step = size(imu_f, 1);
        
        gt_p = dataset.gt.p;
        gt_r = dataset.gt.r;
        gt_t = dataset.gt.t;
        
        % التهيئة الأولية للحالات
        p = [0; 0; 0];
        v = [0; 0; 0];
        q = [1; 0; 0; 0];
        P = eye(9) * 0.1;
        g = [0; 0; -9.81];
        
        est_postation = zeros(num_step, 3);
        est_angles = zeros(num_step, 3);
        P_diag_history = zeros(num_step, 9);
        gnss_index = 1;
        
        % حلقة التشغيل الرئيسية للـ ES-EKF Loop مع تفعيل/تعطيل الحساسات حسب اختيار الواجهة
        for k = 1 : (num_step - 1)
            dt = t_imu(k + 1) - t_imu(k);
            
            % قراءة مدخلات الـ IMU مع مراعاة حالة الـ Checkboxes (لو مقفول نعتبر القراءة صفر أو بدون تأثير)
            current_f = imu_f(k, :)';
            current_w = imu_w(k, :)';
            if ~enable_acc
                current_f = [0; 0; 0];
            end
            if ~enable_gyro
                current_w = [0; 0; 0];
            end
            
            [p, v, q, P] = es_ekf.perdict(p, v, q, P, current_f, current_w, var_imu_f, var_imu_w, g, dt);
            
            % تحديث الـ GNSS فقط لو الـ Checkbox بتاعه مفعل
            if enable_gnss && ((gnss_index < length(gnss_t)) && ((abs(t_imu(k) - gnss_t(gnss_index))) < 1e-4))
                [p, v, q, P] = es_ekf.update(p, v, q, P, gnss_data(gnss_index, :)', R_gnss);
                gnss_index = gnss_index + 1;
            end
            
            est_postation(k, :) = p';
            [Roll, Pitch, Yaw] = rotation.quat2euler(q);
            est_angles(k, :) = [Roll, Pitch, Yaw];
            P_diag_history(k, :) = diag(P)';
        end
        
        % المزامنة والأخطاء
        gt_t_matched = gt_t(1:size(gt_p, 1));
        gt_p_aligned = dataset.gt.p;
        gt_r_aligned = dataset.gt.r;
        
        n = min([size(est_postation,1), size(gt_p_aligned,1), size(est_angles,1), size(gt_r_aligned,1), size(P_diag_history,1)]);
        est_postation  = est_postation(1:n, :);
        est_angles     = est_angles(1:n, :);
        gt_p_aligned   = gt_p_aligned(1:n, :);
        gt_r_aligned   = gt_r_aligned(1:n, :);
        P_diag_history = P_diag_history(1:n, :);
        
        pos_error = est_postation - gt_p_aligned;
        angle_error = est_angles - gt_r_aligned;
        
        % 1. رسم الـ 3D Trajectory الأساسية داخل الـ UIAxes المخصص بالواجهة
        ax = app.AxeTrajectory;
        cla(ax);
        hold(ax, 'off');
        plot3(ax, est_postation(:,1), est_postation(:,2), est_postation(:,3), 'b', 'LineWidth', 1.2, 'DisplayName', 'Estimated');
        hold(ax, 'on');
        plot3(ax, gt_p_aligned(:,1), gt_p_aligned(:,2), gt_p_aligned(:,3), 'Color', [0.85 0.33 0.10], 'LineWidth', 1.5, 'DisplayName', 'Ground Truth');
        plot3(ax, gt_p_aligned(1,1), gt_p_aligned(1,2), gt_p_aligned(1,3), 'o', 'MarkerSize', 8, 'MarkerFaceColor', 'g', 'MarkerEdgeColor', 'k', 'DisplayName', 'Start');
        plot3(ax, gt_p_aligned(end,1), gt_p_aligned(end,2), gt_p_aligned(end,3), 's', 'MarkerSize', 8, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k', 'DisplayName', 'End');
        hold(ax, 'off');
        grid(ax, 'on');
        xlabel(ax, 'Easting [m]');
        ylabel(ax, 'Northing [m]');
        zlabel(ax, 'Up [m]');
        title(ax, '3D Trajectory Estimation');
        legend(ax, 'show');
        view(ax, -60, 20);
        axis(ax, 'equal');


        % 3. رسم الـ 6 Error States مباشرة داخل الـ UIAxes الخاصة بالواجهة (UIAxes1 إلى UIAxes6)
        titles = {"Easting", "Northing", "Up", "Roll", "Pitch", "Yaw"};
        y_labels = {"Meters", "Meters", "Meters", "Radian", "Radian", "Radian"};
        plot_limit = min(n, 8500);
        x_vals = 1 : plot_limit;
        
        for i = 1 : 6
            ax_err = app.(['UIAxes', num2str(i)]); 
            cla(ax_err);
            
            if i <= 3
                p_idx = i;
                err_data = pos_error(x_vals, i);
            else
                p_idx = i + 3;
                angle_idx = i - 3;
                err_data = angle_error(x_vals, angle_idx);
            end
            sigma_bounds = 7 * sqrt(P_diag_history(x_vals, p_idx));
            
            hold(ax_err, 'on');
            plot(ax_err, x_vals, sigma_bounds, 'Color', 'r', 'LineWidth', 1.2);
            plot(ax_err, x_vals, -sigma_bounds, 'Color', 'r', 'LineWidth', 1.2);
            plot(ax_err, x_vals, err_data, 'Color', 'b', 'LineWidth', 1.1, 'DisplayName', 'Error');
            hold(ax_err, 'off');
            
            title(ax_err, titles{i});
            ylabel(ax_err, y_labels{i});
            xlabel(ax_err, 'Steps');
            grid(ax_err, 'on');
            box(ax_err, 'on');
            if i == 1
                legend(ax_err, 'Location', 'best');
            end
        end
   
        
       % sgtitle(error_fig, "Error State (Estimate - Ground Truth) with Covariance Bounds");
    
        end

        % Value changed function: AcclPSDSlider
        function AcclPSDSliderValueChanged(app, event)
            value = app.AcclPSDSlider.Value;
            app.EditFieldAccl.Value = value;
        end

        % Value changed function: EditFieldAccl
        function EditFieldAcclValueChanged(app, event)
            value = app.EditFieldAccl.Value;
            app.AcclPSDSlider.Value = value;
        end

        % Button pushed function: ResetButtonAccl
        function ResetButtonAcclPushed(app, event)
            app.AcclPSDSlider.Value = 0;
            app.EditFieldAccl.Value = 0;
        end

        % Value changed function: GyroPSDSlider
        function GyroPSDSliderValueChanged(app, event)
            value = app.GyroPSDSlider.Value;
            app.EditFieldgyro.Value = value;
        end

        % Value changed function: EditFieldEast
        function EditFieldEastValueChanged(app, event)
            value = app.EditFieldEast.Value;
            app.PovCovEastSlider.Value = value;
        end

        % Button pushed function: ResetButtongyro
        function ResetButtongyroPushed(app, event)
            app.GyroPSDSlider.Value = 0;
            app.EditFieldgyro.Value = 0;
        end

        % Value changed function: BiasinstabilitySlider
        function BiasinstabilitySliderValueChanged(app, event)
            value = app.BiasinstabilitySlider.Value;
            app.EditFieldBias.Value = value;
        end

        % Value changed function: EditFieldBias
        function EditFieldBiasValueChanged(app, event)
            value = app.EditFieldBias.Value;
            app.BiasinstabilitySlider.Value = value;
        end

        % Button pushed function: ResetButtonBias
        function ResetButtonBiasPushed(app, event)
            app.BiasinstabilitySlider.Value = 0;
            app.EditFieldBias.Value = 0;
        end

        % Value changed function: PovCovEastSlider
        function PovCovEastSliderValueChanged(app, event)
            value = app.PovCovEastSlider.Value;
            app.EditFieldEast.Value = value;
        end

        % Value changed function: EditFieldgyro
        function EditFieldgyroValueChanged(app, event)
            value = app.EditFieldgyro.Value;
            app.GyroPSDSlider.Value = value;
        end

        % Button pushed function: ResetButtonEast
        function ResetButtonEastPushed(app, event)
            app.PovCovEastSlider.Value = 0;
            app.EditFieldEast.Value = 0;
        end

        % Value changed function: PovCovNorthSlider
        function PovCovNorthSliderValueChanged(app, event)
            value = app.PovCovNorthSlider.Value;
            app.EditFieldNorth.Value = value;
        end

        % Value changed function: EditFieldNorth
        function EditFieldNorthValueChanged(app, event)
            value = app.EditFieldNorth.Value;
            app.PovCovNorthSlider.Value = value;
        end

        % Button pushed function: ResetButtonNorth
        function ResetButtonNorthPushed(app, event)
            app.PovCovNorthSlider.Value = 0;
            app.EditFieldNorth.Value = 0;
        end

        % Value changed function: PovCovUpSlider
        function PovCovUpSliderValueChanged(app, event)
            value = app.PovCovUpSlider.Value;
            app.EditFieldUP.Value = value;
        end

        % Value changed function: EditFieldUP
        function EditFieldUPValueChanged(app, event)
            value = app.EditFieldUP.Value;
            app.PovCovUpSlider.Value = value;
        end

        % Button pushed function: ResetButtonUp
        function ResetButtonUpPushed(app, event)
            app.PovCovUpSlider.Value = 0;
            app.EditFieldUP.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxeAcc = uiaxes(app.UIFigure);
            title(app.AxeAcc, 'IMU Accerlation')
            xlabel(app.AxeAcc, 'X')
            ylabel(app.AxeAcc, 'Y')
            zlabel(app.AxeAcc, 'Z')
            app.systemtuningworkbenchPanel = uipanel(app.UIFigure);
            app.systemtuningworkbenchPanel.Title = 'system tuning workbench';
            app.DatasetSelectionLabel = uilabel(app.systemtuningworkbenchPanel);
            app.DatasetSelectionLabel.Text = 'Dataset Selection';
            app.DatasetEditField = uieditfield(app.systemtuningworkbenchPanel, 'text');
            app.BrowseButton = uibutton(app.systemtuningworkbenchPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.IMUNoiseParametersQMatrixLabel = uilabel(app.systemtuningworkbenchPanel);
            app.IMUNoiseParametersQMatrixLabel.Text = 'IMU Noise Parameters (Q Matrix)';
            app.EditFieldAccl = uieditfield(app.systemtuningworkbenchPanel, 'numeric');
            app.EditFieldAccl.ValueChangedFcn = createCallbackFcn(app, @EditFieldAcclValueChanged, true);
            app.ResetButtonAccl = uibutton(app.systemtuningworkbenchPanel, 'push');
            app.ResetButtonAccl.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonAcclPushed, true);
            app.ResetButtonAccl.Text = 'Reset';
            app.EditFieldgyro = uieditfield(app.systemtuningworkbenchPanel, 'numeric');
            app.EditFieldgyro.ValueChangedFcn = createCallbackFcn(app, @EditFieldgyroValueChanged, true);
            app.ResetButtongyro = uibutton(app.systemtuningworkbenchPanel, 'push');
            app.ResetButtongyro.ButtonPushedFcn = createCallbackFcn(app, @ResetButtongyroPushed, true);
            app.ResetButtongyro.Text = 'Reset';
            app.EditFieldBias = uieditfield(app.systemtuningworkbenchPanel, 'numeric');
            app.EditFieldBias.ValueChangedFcn = createCallbackFcn(app, @EditFieldBiasValueChanged, true);
            app.ResetButtonBias = uibutton(app.systemtuningworkbenchPanel, 'push');
            app.ResetButtonBias.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonBiasPushed, true);
            app.ResetButtonBias.Text = 'Reset';
            app.GNSSMeasurmentNoiseRMatrixLabel = uilabel(app.systemtuningworkbenchPanel);
            app.GNSSMeasurmentNoiseRMatrixLabel.Text = 'GNSS Measurment Noise (R Matrix)';
            app.ResetButtonUp = uibutton(app.systemtuningworkbenchPanel, 'push');
            app.ResetButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonUpPushed, true);
            app.ResetButtonUp.Text = 'Reset';
            app.ResetButtonNorth = uibutton(app.systemtuningworkbenchPanel, 'push');
            app.ResetButtonNorth.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonNorthPushed, true);
            app.ResetButtonNorth.Text = 'Reset';
            app.ResetButtonEast = uibutton(app.systemtuningworkbenchPanel, 'push');
            app.ResetButtonEast.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonEastPushed, true);
            app.ResetButtonEast.Text = 'Reset';
            app.EditFieldUP = uieditfield(app.systemtuningworkbenchPanel, 'numeric');
            app.EditFieldUP.ValueChangedFcn = createCallbackFcn(app, @EditFieldUPValueChanged, true);
            app.EditFieldNorth = uieditfield(app.systemtuningworkbenchPanel, 'numeric');
            app.EditFieldNorth.ValueChangedFcn = createCallbackFcn(app, @EditFieldNorthValueChanged, true);
            app.EditFieldEast = uieditfield(app.systemtuningworkbenchPanel, 'numeric');
            app.EditFieldEast.ValueChangedFcn = createCallbackFcn(app, @EditFieldEastValueChanged, true);
            app.EnableDisablesensorsLabel = uilabel(app.systemtuningworkbenchPanel);
            app.EnableDisablesensorsLabel.Text = 'EnableDisable sensors';
            app.EnableGNSSCheckBox = uicheckbox(app.systemtuningworkbenchPanel);
            app.EnableGNSSCheckBox.Text = 'Enable GNSS';
            app.EnableAccelemetorCheckBox = uicheckbox(app.systemtuningworkbenchPanel);
            app.EnableAccelemetorCheckBox.Text = 'Enable Accelemetor';
            app.EnableGyroscopeCheckBox = uicheckbox(app.systemtuningworkbenchPanel);
            app.EnableGyroscopeCheckBox.Text = 'Enable Gyroscope';
            app.APPLYTUNINGRUNES_EKFButton = uibutton(app.systemtuningworkbenchPanel, 'push');
            app.APPLYTUNINGRUNES_EKFButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYTUNINGRUNES_EKFButtonPushed, true);
            app.APPLYTUNINGRUNES_EKFButton.Text = 'APPLY  TUNING &RUN ES_EKF';
            app.ErrorPlotsPanel = uipanel(app.systemtuningworkbenchPanel);
            app.ErrorPlotsPanel.Title = 'Error Plots';
            app.UIAxes1 = uiaxes(app.ErrorPlotsPanel);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.ErrorPlotsPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.ErrorPlotsPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.ErrorPlotsPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.ErrorPlotsPanel);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.ErrorPlotsPanel);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.AcclPSDSliderLabel = uilabel(app.systemtuningworkbenchPanel);
            app.AcclPSDSliderLabel.Text = 'Accl  PSD';
            app.AcclPSDSlider = uislider(app.systemtuningworkbenchPanel);
            app.AcclPSDSlider.ValueChangedFcn = createCallbackFcn(app, @AcclPSDSliderValueChanged, true);
            app.GyroPSDSliderLabel = uilabel(app.systemtuningworkbenchPanel);
            app.GyroPSDSliderLabel.Text = 'Gyro  PSD';
            app.GyroPSDSlider = uislider(app.systemtuningworkbenchPanel);
            app.GyroPSDSlider.ValueChangedFcn = createCallbackFcn(app, @GyroPSDSliderValueChanged, true);
            app.BiasinstabilitySliderLabel = uilabel(app.systemtuningworkbenchPanel);
            app.BiasinstabilitySliderLabel.Text = 'Bias instability ';
            app.BiasinstabilitySlider = uislider(app.systemtuningworkbenchPanel);
            app.BiasinstabilitySlider.ValueChangedFcn = createCallbackFcn(app, @BiasinstabilitySliderValueChanged, true);
            app.PovCovUpSliderLabel = uilabel(app.systemtuningworkbenchPanel);
            app.PovCovUpSliderLabel.Text = 'Pov Cov Up';
            app.PovCovUpSlider = uislider(app.systemtuningworkbenchPanel);
            app.PovCovUpSlider.ValueChangedFcn = createCallbackFcn(app, @PovCovUpSliderValueChanged, true);
            app.PovCovNorthSliderLabel = uilabel(app.systemtuningworkbenchPanel);
            app.PovCovNorthSliderLabel.Text = 'Pov Cov North';
            app.PovCovNorthSlider = uislider(app.systemtuningworkbenchPanel);
            app.PovCovNorthSlider.ValueChangedFcn = createCallbackFcn(app, @PovCovNorthSliderValueChanged, true);
            app.PovCovEastSliderLabel = uilabel(app.systemtuningworkbenchPanel);
            app.PovCovEastSliderLabel.Text = 'Pov Cov East';
            app.PovCovEastSlider = uislider(app.systemtuningworkbenchPanel);
            app.PovCovEastSlider.ValueChangedFcn = createCallbackFcn(app, @PovCovEastSliderValueChanged, true);
            app.TrajectoryPanel = uipanel(app.systemtuningworkbenchPanel);
            app.TrajectoryPanel.Title = 'Trajectory';
            app.AxeTrajectory = uiaxes(app.TrajectoryPanel);
            title(app.AxeTrajectory, 'Title')
            xlabel(app.AxeTrajectory, 'X')
            ylabel(app.AxeTrajectory, 'Y')
            zlabel(app.AxeTrajectory, 'Z')
            app.AxeTrajectory.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6];
            app.AxeTrajectory.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end